classdef Anser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        ChannelselectPanel              matlab.ui.container.Panel
        ChannelSelectListBox            matlab.ui.control.ListBox
        HoldCtrltoselectmultiplechannelsLabel  matlab.ui.control.Label
        SystemconfigurationPanel        matlab.ui.container.Panel
        SystemversionButtonGroup        matlab.ui.container.ButtonGroup
        AnserButton                     matlab.ui.control.RadioButton
        DAQversionButtonGroup           matlab.ui.container.ButtonGroup
        NI621XButton                    matlab.ui.control.RadioButton
        MCC1608GXButton                 matlab.ui.control.RadioButton
        BoardIDPanel                    matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        egDev1Board1etcLabel            matlab.ui.control.Label
        ConnectButton_2                 matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationsettingsPanel        matlab.ui.container.Panel
        SensorselectDropDown_4Label     matlab.ui.control.Label
        SensorselectDropDown_4          matlab.ui.control.DropDown
        CalibrateButton                 matlab.ui.control.Button
        CalibrationstatusPanel          matlab.ui.container.Panel
        TextArea_3                      matlab.ui.control.TextArea
        TrackingTab_2                   matlab.ui.container.Tab
        TrackingconfigurationPanel      matlab.ui.container.Panel
        TrackingspeedHzSpinnerLabel     matlab.ui.control.Label
        TrackingspeedHzSpinner          matlab.ui.control.Spinner
        AdvancedPanel                   matlab.ui.container.Panel
        SamplingfrequencyHzSpinnerLabel  matlab.ui.control.Label
        SamplingfrequencyHzSpinner      matlab.ui.control.Spinner
        TrackButton                     matlab.ui.control.StateButton
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        TrackinginformationPanel        matlab.ui.container.Panel
        PositionspersecondHzGaugeLabel  matlab.ui.control.Label
        PositionspersecondHzGauge       matlab.ui.control.LinearGauge
        EditField_8                     matlab.ui.control.NumericEditField
        SensorselectDropDown_7Label     matlab.ui.control.Label
        SensorselectDropDown_7          matlab.ui.control.DropDown
        XEditField_2Label               matlab.ui.control.Label
        XEditField_2                    matlab.ui.control.NumericEditField
        YEditField_2Label               matlab.ui.control.Label
        YEditField_2                    matlab.ui.control.NumericEditField
        ZEditField_2Label               matlab.ui.control.Label
        ZEditField_2                    matlab.ui.control.NumericEditField
        EditField_9Label                matlab.ui.control.Label
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_10Label               matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        OpenIGTLinkTab_2                matlab.ui.container.Tab
        NetworksettingsPanel            matlab.ui.container.Panel
        AddressEditFieldLabel           matlab.ui.control.Label
        AddressEditField                matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        PortEditField                   matlab.ui.control.NumericEditField
        StreamButton                    matlab.ui.control.Button
        ConnectButton_3                 matlab.ui.control.StateButton
        ConnectionstatusPanel           matlab.ui.container.Panel
        TextArea_4                      matlab.ui.control.TextArea
        ApplytransformPanel             matlab.ui.container.Panel
        UITable_3                       matlab.ui.control.Table
        SensorselectDropDown_8Label     matlab.ui.control.Label
        SensorselectDropDown_8          matlab.ui.control.DropDown
        ToolsTab                        matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        TestTab                         matlab.ui.container.Tab
        FFTTab                          matlab.ui.container.Tab
        AboutTab                        matlab.ui.container.Tab
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        AnserEMTLabel                   matlab.ui.control.Label
    end


    properties (Access = public)
        sys = struct(); % Description
    end

    properties (Access = private)
        sensors = [] % Description
        igtlconnection = 0 % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable_3.Data = eye(4);
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            chlist = cell2mat(app.ChannelSelectListBox.Value)';
            chlist = str2num(chlist);
            chlist_string = num2str(chlist);
            app.TextArea_2.Value = strcat('Channels selected: ',{' '},strjoin(app.ChannelSelectListBox.Value,', '));
            
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end
            
            % Determine selected System Type
            if app.AnserButton.Value == 1
                systemtype = 'anser1';
            end
            
                        
            % Determine board ID
            boardid = app.EditField.Value;
            
           
            % Initialise system
            try
                app.sys = fSysSetup(chlist,systemtype, daqtype, boardid);
                % Save sensor list locally
                app.sensors = app.sys.Sensors;
                if length(app.sensors) >= 1
                    app.TrackButton.Enable = 'on';
                end
                tempstr = app.TextArea_2.Value;
                tempstr = strcat(app.TextArea_2.Value,{' '},'Success!');
                app.TextArea_2.Value = tempstr; 
            catch
                app.TextArea_2.Value = 'Error initialising system'; 
            end


        end

        % Callback function
        function ConnectButton_3Pushed(app, event)
            
        end

        % Value changed function: ConnectButton_3
        function ConnectButton_3ValueChanged(app, event)
            value = app.ConnectButton_3.Value;
            if value == 1
                try
                    app.igtlconnection = igtlConnect(app.AddressEditField.Value, app.PortEditField.Value);
                    app.TextArea_4.Value = 'Connection Success!';
                    app.UITable_3.Data = eye(4);
                    app.ConnectButton_3.Text = 'Disconnect';
                catch
                    app.TextArea_4.Value = 'Connection Failed';
                    app.ConnectButton_3.Value = 0;
                end      
            else
                igtlDisconnect(app.igtlconnection);
                app.TextArea_4.Value = 'Connection Closed';
                app.ConnectButton_3.Text = 'Connect';
            end
            
            
        end

        % Callback function
        function SensorselectDropDown_2ValueChanged(app, event)
            value = app.SensorselectDropDown_2.Value;
            if exist('app.sys.Sensors', 'var')
                if ismember(str2num(value),app.sys.Sensors)
                    app.AcquireButton.Enable = 'on';
                else
                    app.AcquireButton.Enable = 'off';
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure = uifigure;
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.Name = 'Anser EMT: An open source electromagnetic tracking platform';
            app.TabGroup = uitabgroup(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ChannelselectPanel = uipanel(app.SetupTab);
            app.ChannelselectPanel.Title = 'Channel select';
            app.ChannelSelectListBox = uilistbox(app.ChannelselectPanel);
            app.ChannelSelectListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ChannelSelectListBox.Multiselect = 'on';
            app.ChannelSelectListBox.Value = {'1'};
            app.HoldCtrltoselectmultiplechannelsLabel = uilabel(app.ChannelselectPanel);
            app.HoldCtrltoselectmultiplechannelsLabel.Text = {'Hold <Ctrl> to select'; 'multiple channels'};
            app.SystemconfigurationPanel = uipanel(app.SetupTab);
            app.SystemconfigurationPanel.Title = 'System configuration';
            app.SystemversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.SystemversionButtonGroup.Title = 'System version';
            app.AnserButton = uiradiobutton(app.SystemversionButtonGroup);
            app.AnserButton.Text = 'Anser';
            app.AnserButton.Value = true;
            app.DAQversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.DAQversionButtonGroup.Title = 'DAQ version';
            app.NI621XButton = uiradiobutton(app.DAQversionButtonGroup);
            app.NI621XButton.Text = 'NI-621X';
            app.NI621XButton.Value = true;
            app.MCC1608GXButton = uiradiobutton(app.DAQversionButtonGroup);
            app.MCC1608GXButton.Text = 'MCC 1608GX';
            app.BoardIDPanel = uipanel(app.SystemconfigurationPanel);
            app.BoardIDPanel.Title = 'Board ID';
            app.EditField = uieditfield(app.BoardIDPanel, 'text');
            app.EditField.Value = 'Dev1';
            app.egDev1Board1etcLabel = uilabel(app.BoardIDPanel);
            app.egDev1Board1etcLabel.Text = '(eg. Dev1, Board1 etc.)';
            app.ConnectButton_2 = uibutton(app.SystemconfigurationPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.TextArea_2 = uitextarea(app.SystemconfigurationPanel);
            app.TextArea_2.Editable = 'off';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationsettingsPanel = uipanel(app.CalibrationTab);
            app.CalibrationsettingsPanel.Title = 'Calibration settings';
            app.SensorselectDropDown_4Label = uilabel(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4Label.Text = 'Sensor select';
            app.SensorselectDropDown_4 = uidropdown(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_4.Value = 'Select';
            app.CalibrateButton = uibutton(app.CalibrationsettingsPanel, 'push');
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.CalibrationstatusPanel = uipanel(app.CalibrationTab);
            app.CalibrationstatusPanel.Title = 'Calibration status';
            app.TextArea_3 = uitextarea(app.CalibrationstatusPanel);
            app.TextArea_3.Editable = 'off';
            app.TrackingTab_2 = uitab(app.TabGroup);
            app.TrackingTab_2.Title = 'Tracking';
            app.TrackingconfigurationPanel = uipanel(app.TrackingTab_2);
            app.TrackingconfigurationPanel.Title = 'Tracking configuration';
            app.TrackingspeedHzSpinnerLabel = uilabel(app.TrackingconfigurationPanel);
            app.TrackingspeedHzSpinnerLabel.Text = 'Tracking speed (Hz)';
            app.TrackingspeedHzSpinner = uispinner(app.TrackingconfigurationPanel);
            app.TrackingspeedHzSpinner.Step = 5;
            app.TrackingspeedHzSpinner.Limits = [1 200];
            app.TrackingspeedHzSpinner.Value = 30;
            app.AdvancedPanel = uipanel(app.TrackingconfigurationPanel);
            app.AdvancedPanel.Title = 'Advanced';
            app.SamplingfrequencyHzSpinnerLabel = uilabel(app.AdvancedPanel);
            app.SamplingfrequencyHzSpinnerLabel.Text = {'Sampling'; 'frequency (Hz)'};
            app.SamplingfrequencyHzSpinner = uispinner(app.AdvancedPanel);
            app.SamplingfrequencyHzSpinner.Step = 100;
            app.SamplingfrequencyHzSpinner.Limits = [500 5000];
            app.SamplingfrequencyHzSpinner.Value = 1000;
            app.TrackButton = uibutton(app.TrackingconfigurationPanel, 'state');
            app.TrackButton.Enable = 'off';
            app.TrackButton.Text = 'Track';
            app.StatusTextAreaLabel = uilabel(app.TrackingconfigurationPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.TrackingconfigurationPanel);
            app.TrackinginformationPanel = uipanel(app.TrackingTab_2);
            app.TrackinginformationPanel.Title = 'Tracking information';
            app.PositionspersecondHzGaugeLabel = uilabel(app.TrackinginformationPanel);
            app.PositionspersecondHzGaugeLabel.Text = 'Positions per second (Hz)';
            app.PositionspersecondHzGauge = uigauge(app.TrackinginformationPanel, 'linear');
            app.EditField_8 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.EditField_8.ValueDisplayFormat = '%.0f';
            app.EditField_8.Editable = 'off';
            app.SensorselectDropDown_7Label = uilabel(app.TrackinginformationPanel);
            app.SensorselectDropDown_7Label.Text = 'Sensor select';
            app.SensorselectDropDown_7 = uidropdown(app.TrackinginformationPanel);
            app.SensorselectDropDown_7.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_7.Value = 'Select';
            app.XEditField_2Label = uilabel(app.TrackinginformationPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.TrackinginformationPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.TrackinginformationPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.ZEditField_2.Editable = 'off';
            app.EditField_9Label = uilabel(app.TrackinginformationPanel);
            app.EditField_9Label.Text = 'θ';
            app.EditField_9 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_10Label = uilabel(app.TrackinginformationPanel);
            app.EditField_10Label.Text = 'φ';
            app.EditField_10 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.EditField_10.Editable = 'off';
            app.OpenIGTLinkTab_2 = uitab(app.TabGroup);
            app.OpenIGTLinkTab_2.Title = 'OpenIGTLink';
            app.NetworksettingsPanel = uipanel(app.OpenIGTLinkTab_2);
            app.NetworksettingsPanel.Title = 'Network settings';
            app.AddressEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.NetworksettingsPanel, 'text');
            app.AddressEditField.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.NetworksettingsPanel, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 18944;
            app.StreamButton = uibutton(app.NetworksettingsPanel, 'push');
            app.StreamButton.Enable = 'off';
            app.StreamButton.Text = 'Stream';
            app.ConnectButton_3 = uibutton(app.NetworksettingsPanel, 'state');
            app.ConnectButton_3.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_3ValueChanged, true);
            app.ConnectButton_3.Text = 'Connect';
            app.ConnectionstatusPanel = uipanel(app.OpenIGTLinkTab_2);
            app.ConnectionstatusPanel.Title = 'Connection status';
            app.TextArea_4 = uitextarea(app.ConnectionstatusPanel);
            app.TextArea_4.Editable = 'off';
            app.ApplytransformPanel = uipanel(app.OpenIGTLinkTab_2);
            app.ApplytransformPanel.Title = 'Apply transform';
            app.UITable_3 = uitable(app.ApplytransformPanel);
            app.UITable_3.ColumnName = {'x'; 'y'; 'z'; 't'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.SensorselectDropDown_8Label = uilabel(app.ApplytransformPanel);
            app.SensorselectDropDown_8Label.Enable = 'off';
            app.SensorselectDropDown_8Label.Text = 'Sensor select';
            app.SensorselectDropDown_8 = uidropdown(app.ApplytransformPanel);
            app.SensorselectDropDown_8.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_8.Enable = 'off';
            app.SensorselectDropDown_8.Value = 'Select';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.TabGroup2 = uitabgroup(app.ToolsTab);
            app.TestTab = uitab(app.TabGroup2);
            app.TestTab.Title = 'Test';
            app.FFTTab = uitab(app.TabGroup2);
            app.FFTTab.Title = 'FFT';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Label_3 = uilabel(app.AboutTab);
            app.Label_3.Text = {'Anser EMT: An open source electromagnetic tracking platform'; ''};
            app.Label_4 = uilabel(app.AboutTab);
            app.Label_4.Text = {'To acknowledge Anser EMT as a platform, please cite the OpenEMT website '; 'and the following publications when publishing work that uses or incorporates Anser EMT:'};
            app.Label2 = uilabel(app.AboutTab);
            app.Label2.Text = {'Copyright (c) 2017, H. A. Jaeger, K O'' Donoghue'; 'All rights reserved.'; 'This code is licensed under the BSD 3-Clause License.'; ''; ''};
            app.Label_5 = uilabel(app.AboutTab);
            app.Label_5.Text = {'Jaeger H.A., Franz A.M., O'' Donoghue K., Seitel., Trauzettel F., Maier-Hein L., Cantillon-Murphy P.'; '"Anser EMT: the first open-source electromagnetic tracking platform for image-guided interventions".'; 'International Journal of Computer Assisted Radiology and Surgery, June 2017, 12(6):1059–1067.'; 'PMID: 28357627, DOI: 10.1007/s11548-017-1568-7'; ''};
            app.Label_6 = uilabel(app.AboutTab);
            app.Label_6.Text = {'This work was supported by the Irish Health Research Board, Science Foundation Ireland'; 'and the New Horizon Framework Programme Grant Agreement (details in paper)'; 'The authors would also like to acknowledge the German Cancer Research Center (DKFZ), Heidelberg'; 'and the Institute of Image-Guided Surgery (IHU), Strasbourg in supporting this work.'};
            app.AnserEMTLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.AnserEMTLabel.Text = 'Anser EMT';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Anser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)
        end
    end
end